classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        GridLayout            matlab.ui.container.GridLayout
        LeftPanel             matlab.ui.container.Panel
        Generate_2            matlab.ui.control.Button
        Group25Label          matlab.ui.control.Label
        CVChallengeLabel      matlab.ui.control.Label
        Generate              matlab.ui.control.Button
        Match                 matlab.ui.control.Button
        Load                  matlab.ui.control.Button
        RightPanel            matlab.ui.container.Panel
        StatusEditField       matlab.ui.control.EditField
        StatusEditFieldLabel  matlab.ui.control.Label
        UIAxes                matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        data_set = cell(0); % Description
        cam_data
        im_data
        ProgressLabel
        ConsoleTextArea
        matches
        world_points
        inlierIdx
    end
        
    
    methods (Access = private)
        function matches = feat_extract_match(~, imageData)
            % Feature extraction code
            tic
            grayImageData = cellfun(@(x) rgb2gray(x), imageData, 'UniformOutput', false);
            surfFeatures = cell(numel(imageData), 1);
            w = waitbar(0,'SIFT features progress...');
            total = numel(imageData);
            for i = 1:numel(imageData)
                surfFeatures{i} = detectSURFFeatures(grayImageData{i});
                start = i;
                progress = ceil((start/total)*100);
                waitbar(progress / 100, w, sprintf('SIFT features progress... %d%% done', progress));
            end
            close(w);
            toc

            % Feature matching code
            tic
            numImages = length(surfFeatures);
            matches = cell(numImages, numImages);
            p = 0;
            m = (numImages-1)/2*numImages;
            fprintf('Number of matches to calculate %d \n', m);
            h = waitbar(0,'Processing Matches...');
            % Calculate the total number of iterations
            m = (numImages-1) * numImages / 2;
            % Initialize current iteration counter
            p = 0;

            for i = 1:numImages
                for j = i+1:numImages
                    [f1,v1] = extractFeatures(grayImageData{i},surfFeatures{i});
                    [f2,v2] = extractFeatures(grayImageData{j},surfFeatures{j});
                    indexPairs = matchFeatures(f1, f2);
                    matchedPoints1 = v1(indexPairs(:,1));
                    matchedPoints2 = v2(indexPairs(:,2));
                    matches{i,j} = struct('P1', matchedPoints1, 'P2', matchedPoints2);
                    p = p + 1;

                    % Calculate progress percentage
                    progress = ceil((p / m) * 100);
                    waitbar(progress / 100, h, sprintf('Processing Matches... %d%% done', progress));
                end
            end
            close(h);
            toc
        end

        function [world_points, inlierIdx] = recon(~, data_set, matches, cam_data)
            %% Get number of images
            numImages = numel(data_set);
            
            %% 3D reconstruction
            % Initialize the cell array to store the 3D world points
            world_points = cell(numImages, numImages);
            inlierIdx = cell(numImages, numImages);
            
            % Initialize waitbar
            h = waitbar(0,'Processing...');
        
            totalIterations = (numImages-1)*numImages/2; % total iterations for waitbar
            currentIteration = 0; % current iteration counter
        
            for i = 1:numImages-1
                for j = i+1:numImages
                % Estimate the fundamental matrix
                [F, inliers] = estimateFundamentalMatrix(matches{i,j}.P1, matches{i,j}.P2, 'Method', 'RANSAC', 'NumTrials', 5000, 'DistanceThreshold', 0.01);
                inlierIdx{i,j} = inliers;
                
                % Compute the essential matrix
                E = cam_data.K' * F * cam_data.K;
                
                % Extract the inlier points
                inlierPoints1 = matches{i,j}.P1(inliers, :);
                inlierPoints2 = matches{i,j}.P2(inliers, :);
        
                % Estimate the relative pose
                relPose = estrelpose(E, cam_data.Intrinsics, inlierPoints1, inlierPoints2);
        
                % Get projection matrices for the two images
                cameraPose1 = rigidtform3d(eye(3), [0; 0; 0]);
                cameraPose2 = rigidtform3d(eye(3), [0; 0; 0]);
                cameraPose2.R = cameraPose1.R * relPose(1,1).R;
                cameraPose2.Translation = cameraPose1.Translation + relPose(1,1).Translation;
        
        
                % Perform triangulationn
                [world_points{i,j}, err, valid] = triangulate(matches{i,j}.P1(inliers, :), matches{i,j}.P2(inliers, :), cameraPose1.A(1:3,:), cameraPose2.A(1:3,:));
                % Update waitbar
                %fprintf('Loop completed with i = %d and j = %d\n', i, j);
                currentIteration = currentIteration + 1;
                waitbar(currentIteration/totalIterations, h, sprintf('Processing... %d/%d done', currentIteration, totalIterations));
                
                end
            end
            % Close waitbar
            close(h);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            % Load the images
            % Prompt the user to select the folder containing the images
            folderPath = uigetdir('Select the folder containing the images');
        
            tic
            % Get a list of all files in the folder
            fileList = dir(fullfile(folderPath, '*.JPG')); 
        
            % Create a cell array to store the images
            data_set = cell(numel(fileList), 1);

            h = waitbar(0,'Loading Images...');
            total = numel(fileList);
        
            % Read and store each image in the cell array
            for i = 1:numel(fileList)
                % Construct the full file path
                filePath = fullfile(folderPath, fileList(i).name);
                data_set{i} = imread(filePath);

                start = i;
                progress = ceil((start/total)*100);
                waitbar(progress / 100, h, sprintf('Image Loading progress... %d%% done', progress));
            end
            % Display the number of images loaded
            close(h)
            app.StatusEditField.Value ="Image Folder loaded!";
        
            % Load the Camera Parameters 
            [baseName, folder] = uigetfile('*.txt', 'Select camera txt');
            path = fullfile(folder, baseName);
            filecontent = readtable(path, 'VariableNamingRule', 'preserve');
            
            k = table2array(filecontent(2,3:end));
            IntrinsicMatrix = [k(3) 0 k(5); 0 k(4) k(6); 0 0 1];
            sz = [k(1),k(2)];
            try
                cam_data = cameraParameters('K',IntrinsicMatrix,'ImageSize',sz);
            catch
                cam_data = cameraParameters('IntrinsicMatrix',IntrinsicMatrix,'ImageSize',sz);
            end

            app.StatusEditField.Value ="Camera parameter loaded!";
        
            % Load the Image Parameters (Not sure how the format is)
            %[baseName, folder] = uigetfile('*.txt', 'Select image txt');
            %path = fullfile(folder, baseName);
            %fid = fopen(path, 'r', 'n', 'UTF-8');
            %for i = 1:4
            %    fgetl(fid); 
            %end
            
            %im_data = struct('IMAGE_ID', {}, 'QW', {}, 'QX', {}, 'QY', {}, 'QZ', {}, 'TX', {}, 'TY', {}, 'TZ', {}, 'CAMERA_ID', {}, 'NAME', {}, 'POINTS2D', {});
            
            %imageCount = 0; % Initialize image counter
            %while ~feof(fid)
                % Read one line of the image data and split it into elements
            %    imageLine = strsplit(fgetl(fid));
            
                % Store elements into the structure
            %    imageCount = imageCount + 1;
            %    im_data(imageCount).IMAGE_ID = str2double(imageLine{1});
            %    im_data(imageCount).QW = str2double(imageLine{2});
            %    im_data(imageCount).QX = str2double(imageLine{3});
            %    im_data(imageCount).QY = str2double(imageLine{4});
            %    im_data(imageCount).QZ = str2double(imageLine{5});
            %    im_data(imageCount).TX = str2double(imageLine{6});
            %    im_data(imageCount).TY = str2double(imageLine{7});
            %    im_data(imageCount).TZ = str2double(imageLine{8});
            %    im_data(imageCount).CAMERA_ID = str2double(imageLine{9});
            %    im_data(imageCount).NAME = imageLine{10};
            
                % Read the second line of points data for each image and split it into elements
            %    pointsLine = strsplit(fgetl(fid));
            
                % Reshape the pointsLine into a 3xN array
            %    pointsData = reshape(str2double(pointsLine), 3, []).';
            
                % Store the points data into the structure
            %    im_data(imageCount).POINTS2D = pointsData;
            %end
            %fclose(fid);

            
            % Store variables in the app object for use in other callback functions
            app.data_set = data_set;
            app.cam_data = cam_data;
            %app.im_data = im_data;
            
            % Display the loaded image count in the console or text area
            fprintf('Total images loaded: %d\n', numel(dataset));
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            view(app.UIAxes, 3);
        end

        % Button pushed function: Match
        function MatchButtonPushed(app, event)
             % Call the matching function
            app.matches = app.feat_extract_match(app.data_set);

            % Display the result in the console or text area
            app.StatusEditField.Value = "Features Matched!";
        end

        % Button pushed function: Generate
        function GenerateButtonPushed(app, event)
            [app.world_points, app.inlierIdx] = recon(app,app.data_set, app.matches, app.cam_data);

            app.StatusEditField.Value = "Reconstruct completed!";
        end

        % Value changed function: StatusEditField
        function StatusEditFieldValueChanged(app, event)
            value = app.StatusEditField.Value;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Load = uibutton(app.LeftPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Match = uibutton(app.LeftPanel, 'push');
            app.Match.ButtonPushedFcn = createCallbackFcn(app, @MatchButtonPushed, true);
            app.Match.Text = 'Match';
            app.Generate = uibutton(app.LeftPanel, 'push');
            app.Generate.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.Generate.Text = 'Generate';
            app.CVChallengeLabel = uilabel(app.LeftPanel);
            app.CVChallengeLabel.Text = 'CV Challenge ';
            app.Group25Label = uilabel(app.LeftPanel);
            app.Group25Label.Text = 'Group 25';
            app.Generate_2 = uibutton(app.LeftPanel, 'push');
            app.Generate_2.Text = 'Reset';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.StatusEditFieldLabel = uilabel(app.RightPanel);
            app.StatusEditFieldLabel.Text = 'Status: ';
            app.StatusEditField = uieditfield(app.RightPanel, 'text');
            app.StatusEditField.ValueChangedFcn = createCallbackFcn(app, @StatusEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end