classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        GridLayout        matlab.ui.container.GridLayout
        LeftPanel         matlab.ui.container.Panel
        Group25Label      matlab.ui.control.Label
        CVChallengeLabel  matlab.ui.control.Label
        Generate          matlab.ui.control.Button
        Prepro            matlab.ui.control.Button
        Load              matlab.ui.control.Button
        RightPanel        matlab.ui.container.Panel
        UIAxes            matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        SelectedImages = ''; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Load
        function LoadButtonPushed(app, event)
            % Open file dialog to select image files
            [fileNames, filePath] = uigetfile({'*.jpg;*.png;*.bmp', 'Image Files'}, 'Select Images', 'MultiSelect', 'on');
            
            % Check if any file was selected
            if isequal(fileNames, 0)
                msg = sprintf('Please select');
                uialert(app.UIFigure, msg, 'Please select', 'Icon', 'success');
                % No file selected, return
                return;
            end
            
            % If a single file is selected, convert it to a cell array for consistency
            if ~iscell(fileNames)
                fileNames = {fileNames};
            end
            
            % Create a cell array to store image data
            image_data = cell(1, numel(fileNames));
            
            % Loop through the selected file names
            for i = 1:numel(fileNames)
                % Get the full file path of the image
                imagePath = fullfile(filePath, fileNames{i});
                
                % Read the image using imread
                img = imread(imagePath);
                
                % Store the image data in the cell array
                image_data{i} = img;
            end
            
            % Save the image data in the app's property for later use
            app.SelectedImages = image_data;

            % Display a message indicating successful saving
            msg = sprintf('Image data saved');
            uialert(app.UIFigure, msg, 'Image Data Saved', 'Icon', 'success');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            view(app.UIAxes, 3);
        end

        % Button pushed function: Prepro
        function PreproButtonPushed(app, event)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {163, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Load = uibutton(app.LeftPanel, 'push');
            app.Load.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.Load.Text = 'Load';
            app.Prepro = uibutton(app.LeftPanel, 'push');
            app.Prepro.ButtonPushedFcn = createCallbackFcn(app, @PreproButtonPushed, true);
            app.Prepro.Text = 'Preprocess';
            app.Generate = uibutton(app.LeftPanel, 'push');
            app.Generate.Text = 'Generate';
            app.CVChallengeLabel = uilabel(app.LeftPanel);
            app.CVChallengeLabel.Text = 'CV Challenge ';
            app.Group25Label = uilabel(app.LeftPanel);
            app.Group25Label.Text = 'Group 25';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end